classdef StartUp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Menu                       matlab.ui.container.Menu
        SavetoFileMenu             matlab.ui.container.Menu
        ExitMenu                   matlab.ui.container.Menu
        OptionsPanel               matlab.ui.container.Panel
        SetSizeDropDown            matlab.ui.control.DropDown
        SetSizeDropDownLabel       matlab.ui.control.Label
        StimTypeDropDown           matlab.ui.control.DropDown
        StimTypeDropDownLabel      matlab.ui.control.Label
        ShortExperimentCheckBox    matlab.ui.control.CheckBox
        RunPracticeTrialsCheckBox  matlab.ui.control.CheckBox
        TrialTypeDropDown          matlab.ui.control.DropDown
        TrialTypeDropDownLabel     matlab.ui.control.Label
        LanguageDropDown           matlab.ui.control.DropDown
        LanguageDropDownLabel      matlab.ui.control.Label
        SessionEditField           matlab.ui.control.NumericEditField
        SessionEditFieldLabel      matlab.ui.control.Label
        SubjectEditField           matlab.ui.control.NumericEditField
        SubjectEditFieldLabel      matlab.ui.control.Label
        CancelButton               matlab.ui.control.Button
        OkButton                   matlab.ui.control.Button
    end


    properties (Access = public)
        expinfo=struct(); % Description

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
          
            movegui(app.UIFigure, 'center');
            % Read in Default values
            app.expinfo.subject = app.SubjectEditField.Value;
            app.expinfo.session = app.SessionEditField.Value;
            app.expinfo.TrialType = app.TrialTypeDropDown.Value;
            app.expinfo.language = app.LanguageDropDown.Value;
            app.expinfo.StimType = app.StimTypeDropDown.Value;
            app.expinfo.doPractice = app.RunPracticeTrialsCheckBox.Value;
            app.expinfo.isShort = app.ShortExperimentCheckBox.Value;
            app.expinfo.SetSize = app.SetSizeDropDown.Value;

            %Create Expinfo Object in Workspace
            assignin('base', 'expinfo', app.expinfo)
        end

        % Callback function: OkButton, UIFigure
        function UIFigureCloseRequest(app, event)


            delete(app)

        end

        % Value changed function: SubjectEditField
        function SubjectEditFieldValueChanged(app, event)
            value_subject = app.SubjectEditField.Value;

            app.expinfo.subject =  value_subject;
            assignin('base', 'expinfo', app.expinfo)

        end

        % Value changed function: SessionEditField
        function SessionEditFieldValueChanged(app, event)
            value_session = app.SessionEditField.Value;
            app.expinfo.session = value_session;
            assignin('base', 'expinfo', app.expinfo)
        end

        % Value changed function: TrialTypeDropDown
        function TrialTypeDropDownValueChanged(app, event)
            value_TrialType = app.TrialTypeDropDown.Value;


            app.expinfo.TrialType = value_TrialType;
            assignin('base', 'expinfo', app.expinfo)
        end

        % Value changed function: LanguageDropDown
        function LanguageDropDownValueChanged(app, event)
            value_lan = app.LanguageDropDown.Value;


            app.expinfo.language = value_lan;
            assignin('base', 'expinfo', app.expinfo)
        end

        % Value changed function: StimTypeDropDown
        function StimTypeDropDownValueChanged(app, event)
            value_stimtype = app.StimTypeDropDown.Value;


            app.expinfo.StimType = value_stimtype;
            assignin('base', 'expinfo', app.expinfo)
        end

        % Value changed function: RunPracticeTrialsCheckBox
        function RunPracticeTrialsCheckBoxValueChanged(app, event)
            value_prac = app.RunPracticeTrialsCheckBox.Value;


            app.expinfo.doPractice = value_prac;
            assignin('base', 'expinfo', app.expinfo)
        end

        % Value changed function: ShortExperimentCheckBox
        function ShortExperimentCheckBoxValueChanged(app, event)
            value_short = app.ShortExperimentCheckBox.Value;


            app.expinfo.isShort = value_short;
            assignin('base', 'expinfo', app.expinfo)
        end

        % Menu selected function: SavetoFileMenu
        function SavetoFileMenuSelected(app, event)

        end

        % Button down function: UIFigure
        function UIFigureButtonDown(app, event)

            assignin('base', 'expinfo', app.expinfo)
        end

        % Value changed function: SetSizeDropDown
        function SetSizeDropDownValueChanged(app, event)
            value_setsize = app.SetSizeDropDown.Value;
            app.expinfo.SetSize = value_setsize;
            assignin('base', 'expinfo', app.expinfo)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @UIFigureButtonDown, true);
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.SavetoFileMenu = uimenu(app.Menu);
            app.SavetoFileMenu.MenuSelectedFcn = createCallbackFcn(app, @SavetoFileMenuSelected, true);
            app.SavetoFileMenu.Text = 'Save to File';
            app.ExitMenu = uimenu(app.Menu);
            app.ExitMenu.Text = 'Exit';
            app.OkButton = uibutton(app.UIFigure, 'push');
            app.OkButton.ButtonPushedFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.OkButton.Text = 'Ok';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.Text = 'Cancel';
            app.SubjectEditFieldLabel = uilabel(app.UIFigure);
            app.SubjectEditFieldLabel.Text = 'Subject';
            app.SubjectEditField = uieditfield(app.UIFigure, 'numeric');
            app.SubjectEditField.ValueChangedFcn = createCallbackFcn(app, @SubjectEditFieldValueChanged, true);
            app.SessionEditFieldLabel = uilabel(app.UIFigure);
            app.SessionEditFieldLabel.Text = 'Session';
            app.SessionEditField = uieditfield(app.UIFigure, 'numeric');
            app.SessionEditField.ValueChangedFcn = createCallbackFcn(app, @SessionEditFieldValueChanged, true);
            app.OptionsPanel = uipanel(app.UIFigure);
            app.OptionsPanel.Title = 'Options';
            app.LanguageDropDownLabel = uilabel(app.OptionsPanel);
            app.LanguageDropDownLabel.Text = 'Language';
            app.LanguageDropDown = uidropdown(app.OptionsPanel);
            app.LanguageDropDown.Items = {'German', 'English', ''};
            app.LanguageDropDown.ValueChangedFcn = createCallbackFcn(app, @LanguageDropDownValueChanged, true);
            app.LanguageDropDown.Tooltip = {'Select Desired Language'};
            app.LanguageDropDown.Placeholder = 'Select Language';
            app.LanguageDropDown.Value = '';
            app.TrialTypeDropDownLabel = uilabel(app.OptionsPanel);
            app.TrialTypeDropDownLabel.Text = 'Trial Type';
            app.TrialTypeDropDown = uidropdown(app.OptionsPanel);
            app.TrialTypeDropDown.Items = {'Pre Cue', 'Post Cue', 'Mixed', ''};
            app.TrialTypeDropDown.ItemsData = [1 2 3];
            app.TrialTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @TrialTypeDropDownValueChanged, true);
            app.TrialTypeDropDown.Tooltip = {'Select Cue Condition'};
            app.TrialTypeDropDown.Placeholder = 'Select Trial Type';
            app.TrialTypeDropDown.Value = 1;
            app.RunPracticeTrialsCheckBox = uicheckbox(app.OptionsPanel);
            app.RunPracticeTrialsCheckBox.ValueChangedFcn = createCallbackFcn(app, @RunPracticeTrialsCheckBoxValueChanged, true);
            app.RunPracticeTrialsCheckBox.Text = 'Run Practice Trials';
            app.RunPracticeTrialsCheckBox.Value = true;
            app.ShortExperimentCheckBox = uicheckbox(app.OptionsPanel);
            app.ShortExperimentCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShortExperimentCheckBoxValueChanged, true);
            app.ShortExperimentCheckBox.Text = 'Short Experiment';
            app.StimTypeDropDownLabel = uilabel(app.OptionsPanel);
            app.StimTypeDropDownLabel.Text = 'Stimulus Type';
            app.StimTypeDropDown = uidropdown(app.OptionsPanel);
            app.StimTypeDropDown.Items = {'Verbal', 'Numerical', ''};
            app.StimTypeDropDown.ItemsData = {'Words', 'Numbers '};
            app.StimTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @StimTypeDropDownValueChanged, true);
            app.StimTypeDropDown.Tooltip = {'Select Stimulus Domain'};
            app.StimTypeDropDown.Placeholder = 'Select Stimulus Type';
            app.StimTypeDropDown.Value = 'Words';
            app.SetSizeDropDownLabel = uilabel(app.OptionsPanel);
            app.SetSizeDropDownLabel.Text = 'Set Size';
            app.SetSizeDropDown = uidropdown(app.OptionsPanel);
            app.SetSizeDropDown.Items = {'3', '5', '6'};
            app.SetSizeDropDown.ItemsData = [3 5 6];
            app.SetSizeDropDown.ValueChangedFcn = createCallbackFcn(app, @SetSizeDropDownValueChanged, true);
            app.SetSizeDropDown.Placeholder = 'Select Set Size';
            app.SetSizeDropDown.Value = 3;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = StartUp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end